classdef poleZeroTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SnaptoRealAxisCheckBox      matlab.ui.control.CheckBox
        ConjugatePairsCheckBox      matlab.ui.control.CheckBox
        TimeResponseOutputLabel     matlab.ui.control.Label
        BoundsforPoleZeroPlotLabel  matlab.ui.control.Label
        ImaginaryEditField          matlab.ui.control.NumericEditField
        ImaginaryEditFieldLabel     matlab.ui.control.Label
        RealEditField               matlab.ui.control.NumericEditField
        RealEditFieldLabel          matlab.ui.control.Label
        TimeSpanMaxEditField        matlab.ui.control.NumericEditField
        TimespanMinEditField        matlab.ui.control.NumericEditField
        TimespanLabel               matlab.ui.control.Label
        ClearAllPointsButton        matlab.ui.control.Button
        DeletePointButton           matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        AddPolesButton              matlab.ui.control.Button
        AddZerosButton              matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        poleZeroApp PoleZeroApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.poleZeroApp = PoleZeroApp(app.UIAxes, app.UIAxes2);
            
            % initialize static (persistent) variable
            app.poleZeroApp.pointTracker.primary.zeroes.snapMode.mode = ...
                app.SnaptoRealAxisCheckBox.Value;
            app.poleZeroApp.pointTracker.primary.zeroes.snapMode.tolerance = ... 
                app.ImaginaryEditField.Value ./ 15;
        end

        % Button pushed function: AddZerosButton
        function AddZerosButtonPushed(app, event)
            app.poleZeroApp.addPoints(app.poleZeroApp.zeroStruct);
        end

        % Button pushed function: AddPolesButton
        function AddPolesButtonPushed(app, event)
            app.poleZeroApp.addPoints(app.poleZeroApp.poleStruct);
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            app.poleZeroApp.deletePoints();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.poleZeroApp.stopActions();
        end

        % Button pushed function: ClearAllPointsButton
        function ClearAllPointsButtonPushed(app, event)
            app.poleZeroApp.clearPoints();
        end

        % Value changed function: TimespanMinEditField
        function TimespanMinEditFieldValueChanged(app, event)
            app.poleZeroApp.timeSpan(1) = app.TimespanMinEditField.Value;
            app.poleZeroApp.plotTimeDomainResponse();
        end

        % Value changed function: TimeSpanMaxEditField
        function TimeSpanMaxEditFieldValueChanged(app, event)
            app.poleZeroApp.timeSpan(2) = app.TimeSpanMaxEditField.Value;
            app.poleZeroApp.plotTimeDomainResponse();
            app.poleZeroApp
        end

        % Value changed function: RealEditField
        function RealEditFieldValueChanged(app, event)
            app.poleZeroApp.bounds(1, :) = [-abs(app.RealEditField.Value), abs(app.RealEditField.Value)];
            app.poleZeroApp.clearPoints();
            app.poleZeroApp.setupAxes();
        end

        % Value changed function: ImaginaryEditField
        function ImaginaryEditFieldValueChanged(app, event)
            app.poleZeroApp.bounds(2, :) = [-abs(app.ImaginaryEditField.Value), abs(app.ImaginaryEditField.Value)];
            app.poleZeroApp.clearPoints();
            app.poleZeroApp.setupAxes();
            app.poleZeroApp.pointTracker.primary.zeroes.snapMode.tolerance = ... 
                app.ImaginaryEditField.Value ./ 15;
        end

        % Value changed function: ConjugatePairsCheckBox
        function ConjugatePairsCheckBoxValueChanged(app, event)
            app.poleZeroApp.conjugateMode = app.ConjugatePairsCheckBox.Value;
        end

        % Value changed function: SnaptoRealAxisCheckBox
        function SnaptoRealAxisCheckBoxValueChanged(app, event)
            % update static (persistent) variable
            app.poleZeroApp.pointTracker.primary.zeroes.snapMode.mode = ...
                app.SnaptoRealAxisCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pole-Zero Plot')
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Imaginary')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Time Response Output')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Response')
            zlabel(app.UIAxes2, 'Z')
            app.AddZerosButton = uibutton(app.UIFigure, 'push');
            app.AddZerosButton.ButtonPushedFcn = createCallbackFcn(app, @AddZerosButtonPushed, true);
            app.AddZerosButton.Text = 'Add Zeros';
            app.AddPolesButton = uibutton(app.UIFigure, 'push');
            app.AddPolesButton.ButtonPushedFcn = createCallbackFcn(app, @AddPolesButtonPushed, true);
            app.AddPolesButton.Text = 'Add Poles';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DeletePointButton = uibutton(app.UIFigure, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete Point';
            app.ClearAllPointsButton = uibutton(app.UIFigure, 'push');
            app.ClearAllPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllPointsButtonPushed, true);
            app.ClearAllPointsButton.Text = 'Clear All Points';
            app.TimespanLabel = uilabel(app.UIFigure);
            app.TimespanLabel.Text = 'Timespan';
            app.TimespanMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimespanMinEditField.ValueChangedFcn = createCallbackFcn(app, @TimespanMinEditFieldValueChanged, true);
            app.TimeSpanMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeSpanMaxEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSpanMaxEditFieldValueChanged, true);
            app.TimeSpanMaxEditField.Value = 5;
            app.RealEditFieldLabel = uilabel(app.UIFigure);
            app.RealEditFieldLabel.Text = 'Real';
            app.RealEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealEditField.ValueChangedFcn = createCallbackFcn(app, @RealEditFieldValueChanged, true);
            app.RealEditField.Value = 2;
            app.ImaginaryEditFieldLabel = uilabel(app.UIFigure);
            app.ImaginaryEditFieldLabel.Text = 'Imaginary';
            app.ImaginaryEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImaginaryEditField.ValueChangedFcn = createCallbackFcn(app, @ImaginaryEditFieldValueChanged, true);
            app.ImaginaryEditField.Value = 3;
            app.BoundsforPoleZeroPlotLabel = uilabel(app.UIFigure);
            app.BoundsforPoleZeroPlotLabel.Text = '+/- Bounds for Pole-Zero Plot';
            app.TimeResponseOutputLabel = uilabel(app.UIFigure);
            app.TimeResponseOutputLabel.Text = 'Time-Response Output';
            app.ConjugatePairsCheckBox = uicheckbox(app.UIFigure);
            app.ConjugatePairsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConjugatePairsCheckBoxValueChanged, true);
            app.ConjugatePairsCheckBox.Text = 'Conjugate Pairs';
            app.ConjugatePairsCheckBox.Value = true;
            app.SnaptoRealAxisCheckBox = uicheckbox(app.UIFigure);
            app.SnaptoRealAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @SnaptoRealAxisCheckBoxValueChanged, true);
            app.SnaptoRealAxisCheckBox.Text = 'Snap to Real Axis';
            app.SnaptoRealAxisCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poleZeroTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end